classdef graph_preference_term < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        nonpreferredactionSlider       matlab.ui.control.Slider
        nonpreferredactionSliderLabel  matlab.ui.control.Label
        preferredactionSlider          matlab.ui.control.Slider
        preferredactionSliderLabel     matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        preferencenoiseSlider          matlab.ui.control.Slider
        preferencenoiseSliderLabel     matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: preferredactionSlider
        function preferredactionSliderValueChanged(app, event)
            value = app.preferredactionSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value
            value = event.Source.MajorTicks(minIdx);

            % get inputted variables
            action1 = value;
            action2 = app.nonpreferredactionSlider.Value;
            noise = app.preferencenoiseSlider.Value

            % plot
            syms R1 R2
            fsurf(-log(1 + exp((R1 - R2)/noise)^(-1)), [-1 1], 'parent', app.UIAxes)

        end

        % Value changed function: nonpreferredactionSlider
        function nonpreferredactionSliderValueChanged(app, event)
            value = app.nonpreferredactionSlider.Value
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value
            value = event.Source.MajorTicks(minIdx);

            % get inputted variables
            action1 = app.preferredactionSlider.Value;
            action2 = value;
            noise = app.preferencenoiseSlider.Value;

            % plot
            syms R1 R2
            fsurf(-log(1 + exp(-(R1 - R2)/noise)^(-1)), [-1 1], 'parent', app.UIAxes)


        end

        % Value changed function: preferencenoiseSlider
        function preferencenoiseSliderValueChanged(app, event)
            % get inputted variables
            action1 = app.preferredactionSlider.Value;
            action2 = app.nonpreferredactionSlider.Value
            noise = app.preferencenoiseSlider.Value;

            % plot
            syms R1 R2
            fsurf(-log(1 + exp((R1 - R2)/noise)^(-1)), [-1 1], 'parent', app.UIAxes)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {430, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.preferredactionSliderLabel = uilabel(app.LeftPanel);
            app.preferredactionSliderLabel.Text = {'preferred'; 'action'};
            app.preferredactionSlider = uislider(app.LeftPanel);
            app.preferredactionSlider.Limits = [1 20];
            app.preferredactionSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.preferredactionSlider.ValueChangedFcn = createCallbackFcn(app, @preferredactionSliderValueChanged, true);
            app.preferredactionSlider.MinorTicks = [];
            app.preferredactionSlider.Value = 5;
            app.nonpreferredactionSliderLabel = uilabel(app.LeftPanel);
            app.nonpreferredactionSliderLabel.Text = {'non-preferred'; 'action'};
            app.nonpreferredactionSlider = uislider(app.LeftPanel);
            app.nonpreferredactionSlider.Limits = [1 20];
            app.nonpreferredactionSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.nonpreferredactionSlider.ValueChangedFcn = createCallbackFcn(app, @nonpreferredactionSliderValueChanged, true);
            app.nonpreferredactionSlider.MinorTicks = [];
            app.nonpreferredactionSlider.Value = 12;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'preference term in S(r)')
            xlabel(app.UIAxes, 'non-preferred action''s reward')
            ylabel(app.UIAxes, 'preferred action''s reward')
            zlabel(app.UIAxes, '-ln(sigmoid(r_{pref}-r_{non-pref}/noise)')
            app.preferencenoiseSliderLabel = uilabel(app.RightPanel);
            app.preferencenoiseSliderLabel.Text = 'preference noise';
            app.preferencenoiseSlider = uislider(app.RightPanel);
            app.preferencenoiseSlider.Limits = [0 0.2];
            app.preferencenoiseSlider.ValueChangedFcn = createCallbackFcn(app, @preferencenoiseSliderValueChanged, true);
            app.preferencenoiseSlider.MinorTicks = [];
            app.preferencenoiseSlider.Value = 0.02;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graph_preference_term

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end