classdef graph_prior_term < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        correspondinginversepriorcovarianceEditField  matlab.ui.control.NumericEditField
        correspondinginversepriorcovarianceLabel  matlab.ui.control.Label
        action2Slider              matlab.ui.control.Slider
        action2SliderLabel         matlab.ui.control.Label
        action1Slider              matlab.ui.control.Slider
        action1SliderLabel         matlab.ui.control.Label
        enterButton                matlab.ui.control.Button
        correspondingpriorcovarianceEditField  matlab.ui.control.NumericEditField
        correspondingpriorcovariancetermLabel  matlab.ui.control.Label
        lengthscaleEditField       matlab.ui.control.NumericEditField
        lengthscaleEditFieldLabel  matlab.ui.control.Label
        varianceEditField          matlab.ui.control.NumericEditField
        varianceEditFieldLabel     matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: enterButton
        function enterButtonPushed(app, event)
            
            % constants
            GP_noise_var = 1e-05;
            X = (1:20).';  % actions

            % get inputted variables
            action1 = app.action1Slider.Value;
            action2 = app.action2Slider.Value;
            variance = app.varianceEditField.Value;
            lengthscales = app.lengthscaleEditField.Value;

            %%% calculations
            % normalize X by lengthscales in each dimension
            Xs = X./lengthscales;
            % Calculate (x-x')^2 as x^2 - 2xx' + x'^2
            Xsq = sum(Xs.^2,2);
            r2 = -2*(Xs*Xs') + (Xsq + Xsq');
            r2 = max(r2,0); % make sure all elements are positive
            % RBF
            cov = variance * exp(-0.5 * r2);
            % Add GP noise variance
            cov = cov + GP_noise_var * eye(size(X,1));
            
            % results
            prior_cov = cov;
            prior_cov_inv = inv(prior_cov);

            % output
            app.correspondingpriorcovarianceEditField.Value = prior_cov(action1, action2);
            app.correspondinginversepriorcovarianceEditField.Value = prior_cov_inv(action1, action2);

            % plot
            syms R1 R2
            fsurf(0.5 * R1 * R2 * prior_cov_inv(action1, action2), [-1 1], 'parent', app.UIAxes)


        end

        % Value changed function: action1Slider
        function action1SliderValueChanged(app, event)
            value = app.action1Slider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value
            value = event.Source.MajorTicks(minIdx);


            % constants
            GP_noise_var = 1e-05;
            X = (1:20).';  % actions

            % get inputted variables
            action1 = value;
            action2 = app.action2Slider.Value;
            variance = app.varianceEditField.Value;
            lengthscales = app.lengthscaleEditField.Value;

            %%% calculations
            % normalize X by lengthscales in each dimension
            Xs = X./lengthscales;
            % Calculate (x-x')^2 as x^2 - 2xx' + x'^2
            Xsq = sum(Xs.^2,2);
            r2 = -2*(Xs*Xs') + (Xsq + Xsq');
            r2 = max(r2,0); % make sure all elements are positive
            % RBF
            cov = variance * exp(-0.5 * r2);
            % Add GP noise variance
            cov = cov + GP_noise_var * eye(size(X,1));
            
            % results
            prior_cov = cov;
            prior_cov_inv = inv(prior_cov);

            % output
            app.correspondingpriorcovarianceEditField.Value = prior_cov(action1, action2);
            app.correspondinginversepriorcovarianceEditField.Value = prior_cov_inv(action1, action2);

            % plot
            syms R1 R2
            fsurf(0.5 * R1 * R2 * prior_cov_inv(action1, action2), [-1 1],  'parent', app.UIAxes)

        end

        % Value changed function: action2Slider
        function action2SliderValueChanged(app, event)
            value = app.action2Slider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value 
            value = event.Source.MajorTicks(minIdx);

            % constants
            GP_noise_var = 1e-05;
            X = (1:20).';  % actions

            % get inputted variables
            action1 = app.action1Slider.Value;
            action2 = value;
            variance = app.varianceEditField.Value;
            lengthscales = app.lengthscaleEditField.Value;

            %%% calculations
            % normalize X by lengthscales in each dimension
            Xs = X./lengthscales;
            % Calculate (x-x')^2 as x^2 - 2xx' + x'^2
            Xsq = sum(Xs.^2,2);
            r2 = -2*(Xs*Xs') + (Xsq + Xsq');
            r2 = max(r2,0); % make sure all elements are positive
            % RBF
            cov = variance * exp(-0.5 * r2);
            % Add GP noise variance
            cov = cov + GP_noise_var * eye(size(X,1));
            
            % results
            prior_cov = cov;
            prior_cov_inv = inv(prior_cov);

            % output
            app.correspondingpriorcovarianceEditField.Value = prior_cov(action1, action2);
            app.correspondinginversepriorcovarianceEditField.Value = prior_cov_inv(action1, action2);

            % plot
            syms R1 R2
            fsurf(0.5 * R1 * R2 * prior_cov_inv(action1, action2), [-1 1], 'parent', app.UIAxes)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {430, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.varianceEditFieldLabel = uilabel(app.LeftPanel);
            app.varianceEditFieldLabel.Text = 'variance';
            app.varianceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.varianceEditField.Limits = [0 Inf];
            app.varianceEditField.Value = 1;
            app.lengthscaleEditFieldLabel = uilabel(app.LeftPanel);
            app.lengthscaleEditFieldLabel.Text = 'lengthscale';
            app.lengthscaleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.lengthscaleEditField.Limits = [0 Inf];
            app.lengthscaleEditField.Value = 5;
            app.correspondingpriorcovariancetermLabel = uilabel(app.LeftPanel);
            app.correspondingpriorcovariancetermLabel.Text = {'corresponding'; 'prior covariance'};
            app.correspondingpriorcovarianceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.correspondingpriorcovarianceEditField.ValueDisplayFormat = '%5.4f';
            app.correspondingpriorcovarianceEditField.Value = 0.3753110988514;
            app.enterButton = uibutton(app.LeftPanel, 'push');
            app.enterButton.ButtonPushedFcn = createCallbackFcn(app, @enterButtonPushed, true);
            app.enterButton.Text = 'enter';
            app.action1SliderLabel = uilabel(app.LeftPanel);
            app.action1SliderLabel.Text = 'action 1';
            app.action1Slider = uislider(app.LeftPanel);
            app.action1Slider.Limits = [1 20];
            app.action1Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.action1Slider.ValueChangedFcn = createCallbackFcn(app, @action1SliderValueChanged, true);
            app.action1Slider.MinorTicks = [];
            app.action1Slider.Value = 5;
            app.action2SliderLabel = uilabel(app.LeftPanel);
            app.action2SliderLabel.Text = 'action 2';
            app.action2Slider = uislider(app.LeftPanel);
            app.action2Slider.Limits = [1 20];
            app.action2Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.action2Slider.ValueChangedFcn = createCallbackFcn(app, @action2SliderValueChanged, true);
            app.action2Slider.MinorTicks = [];
            app.action2Slider.Value = 12;
            app.correspondinginversepriorcovarianceLabel = uilabel(app.LeftPanel);
            app.correspondinginversepriorcovarianceLabel.Text = {'corresponding'; 'inverse prior'; ' covariance'};
            app.correspondinginversepriorcovarianceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.correspondinginversepriorcovarianceEditField.Value = 3661.21231505781;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'covariance term in S(r)')
            xlabel(app.UIAxes, 'action 1 reward')
            ylabel(app.UIAxes, 'action 2 reward')
            zlabel(app.UIAxes, 'reward 1 * reward 2 * inv cov coord')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graph_prior_term

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end